classdef Examen_1Parcial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EligeuntipodesolucinLabel       matlab.ui.control.Label
        IntroduceelngulodelabaseydecadaarticulacinLabel  matlab.ui.control.Label
        IntroducelalongituddecadaeslabnLabel  matlab.ui.control.Label
        ResultadosTextArea              matlab.ui.control.TextArea
        ResultadosTextAreaLabel         matlab.ui.control.Label
        SolucinhomogneaCheckBox         matlab.ui.control.CheckBox
        SolucingeomtricaCheckBox        matlab.ui.control.CheckBox
        SimularButton                   matlab.ui.control.Button
        ngulodelabaseEditField          matlab.ui.control.NumericEditField
        ngulodelabaseEditFieldLabel     matlab.ui.control.Label
        nguloterceraarticulacinEditField  matlab.ui.control.NumericEditField
        nguloterceraarticulacinEditFieldLabel  matlab.ui.control.Label
        ngulosegundaarticulacinEditField  matlab.ui.control.NumericEditField
        ngulosegundaarticulacinEditFieldLabel  matlab.ui.control.Label
        nguloprimeraarticulacinEditField  matlab.ui.control.NumericEditField
        nguloprimeraarticulacinEditFieldLabel  matlab.ui.control.Label
        LongitudtercereslabnEditField   matlab.ui.control.NumericEditField
        LongitudtercereslabnEditFieldLabel  matlab.ui.control.Label
        LongitudsegundoeslabnEditField  matlab.ui.control.NumericEditField
        LongitudsegundoeslabnEditFieldLabel  matlab.ui.control.Label
        LongitudprimereslabnEditField   matlab.ui.control.NumericEditField
        LongitudprimereslabnEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             DibujaEjes_GUI(app.UIAxes, 10);
        end

        % Callback function: not associated with a component
        function SolucingeomtricaCheckBoxValueChanged(app, event)
          
        end

        % Callback function: not associated with a component
        function SolucinmatricialCheckBoxValueChanged(app, event)
         
        end

        % Callback function: not associated with a component
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
         %% Declaración de longitudes y ángulos
              l1 = app.LongitudprimereslabnEditField.Value;
              l2 = app.LongitudsegundoeslabnEditField.Value;
              l3 = app.LongitudtercereslabnEditField.Value;
              Theta1 = app.ngulodelabaseEditField.Value;
              rot_base = Theta1*pi/180;
              Theta2 = app.nguloprimeraarticulacinEditField.Value;
              rot_1 = Theta2*pi/180;
              Theta3 = app.ngulosegundaarticulacinEditField.Value;
              rot_2 = Theta3*pi/180;
              Theta4 = app.nguloterceraarticulacinEditField.Value;
              rot_3 = Theta4*pi/180;

        %% Solución Homogénea   
        if app.SolucinhomogneaCheckBox.Value
            % Display
              app.ResultadosTextArea.Value = { ...
        sprintf('1. Rz(th1) = %.1f°', Theta1); ...
        '2. Ry(90°)'; ...
        '3. Rz(90°)'; ...
        sprintf('4. Rz(th2) = %.1f°', Theta2); ...
        sprintf('5. Tx(l1)  = %.2f', l1); ...
        sprintf('6. Rz(th3) = %.1f°', Theta3); ...
        sprintf('7. Tx(l2)  = %.2f', l2); ...
        sprintf('8. Rz(th4) = %.1f°', Theta4); ...
        sprintf('9. Tx(l3)  = %.2f', l3)};
              
               %Rotar base
               for Theta1 = 0:0.05:rot_base
                    Theta2 = 0; Theta3 = 0; Theta4 = 0;
                    SolucionHomogenea(app, l1, l2, l3, Theta1, Theta2, Theta3, Theta4);
               end
               %Rotar el primer eslabon
               for Theta2 = 0:0.05:rot_1
                   Theta3 = 0; Theta4 = 0;
                   SolucionHomogenea(app, l1, l2, l3, Theta1, Theta2, Theta3, Theta4);
               end
               %Rotar el segundo eslabon
               for Theta3 = 0:0.05:rot_2
                   Theta4 = 0;
                   SolucionHomogenea(app, l1, l2, l3, Theta1, Theta2, Theta3, Theta4);
               end
               %Rotar el tercer eslabon
               for Theta4 = 0:0.05:rot_3
                   SolucionHomogenea(app, l1, l2, l3, Theta1, Theta2, Theta3, Theta4);
               end
        
         %% Solución geométrica
         elseif app.SolucingeomtricaCheckBox.Value
                %Rotar la base
                for Theta1 = 0:0.05:rot_base
                    Theta2 = 0; Theta3 = 0; Theta4 = 0;
                    dibujaBrazoGeo_GUI(app, l1, l2, l3, Theta1, Theta2, Theta3, Theta4);
                end
                %Rotar el primer eslabon
                for Theta2 = 0:0.05:rot_1
                    Theta3 = 0; Theta4 = 0;
                    dibujaBrazoGeo_GUI(app, l1, l2, l3, Theta1, Theta2, Theta3, Theta4);
                end
                %Rotar el segundo eslabon
                for Theta3 = 0:0.05:rot_2
                    Theta4 = 0;
                    dibujaBrazoGeo_GUI(app, l1, l2, l3, Theta1, Theta2, Theta3, Theta4);
                end
                %Rotar el tercer eslabon
                for Theta4 = 0:0.05:rot_3
                    dibujaBrazoGeo_GUI(app, l1, l2, l3, Theta1, Theta2, Theta3, Theta4);
                end
         end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulación brazo robótico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LongitudprimereslabnEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudprimereslabnEditFieldLabel.Text = 'Longitud primer eslabón';
            app.LongitudprimereslabnEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudsegundoeslabnEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudsegundoeslabnEditFieldLabel.Text = 'Longitud segundo eslabón';
            app.LongitudsegundoeslabnEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudtercereslabnEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudtercereslabnEditFieldLabel.Text = 'Longitud tercer eslabón';
            app.LongitudtercereslabnEditField = uieditfield(app.UIFigure, 'numeric');
            app.nguloprimeraarticulacinEditFieldLabel = uilabel(app.UIFigure);
            app.nguloprimeraarticulacinEditFieldLabel.Text = 'Ángulo primera articulación';
            app.nguloprimeraarticulacinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ngulosegundaarticulacinEditFieldLabel = uilabel(app.UIFigure);
            app.ngulosegundaarticulacinEditFieldLabel.Text = 'Ángulo segunda articulación';
            app.ngulosegundaarticulacinEditField = uieditfield(app.UIFigure, 'numeric');
            app.nguloterceraarticulacinEditFieldLabel = uilabel(app.UIFigure);
            app.nguloterceraarticulacinEditFieldLabel.Text = 'Ángulo tercera articulación';
            app.nguloterceraarticulacinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ngulodelabaseEditFieldLabel = uilabel(app.UIFigure);
            app.ngulodelabaseEditFieldLabel.Text = 'Ángulo de la base';
            app.ngulodelabaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.SolucingeomtricaCheckBox = uicheckbox(app.UIFigure);
            app.SolucingeomtricaCheckBox.Text = 'Solución geométrica';
            app.SolucinhomogneaCheckBox = uicheckbox(app.UIFigure);
            app.SolucinhomogneaCheckBox.Text = 'Solución homogénea';
            app.ResultadosTextAreaLabel = uilabel(app.UIFigure);
            app.ResultadosTextAreaLabel.Text = 'Resultados';
            app.ResultadosTextArea = uitextarea(app.UIFigure);
            app.IntroducelalongituddecadaeslabnLabel = uilabel(app.UIFigure);
            app.IntroducelalongituddecadaeslabnLabel.Text = 'Introduce la longitud de cada eslabón';
            app.IntroduceelngulodelabaseydecadaarticulacinLabel = uilabel(app.UIFigure);
            app.IntroduceelngulodelabaseydecadaarticulacinLabel.Text = 'Introduce el ángulo de la base y de cada articulación';
            app.EligeuntipodesolucinLabel = uilabel(app.UIFigure);
            app.EligeuntipodesolucinLabel.Text = 'Elige un tipo de solución';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Examen_1Parcial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end